classdef EqualizerApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        MainGrid          matlab.ui.container.GridLayout
        DisplayGrid       matlab.ui.container.GridLayout
        WaveformAxes      matlab.ui.control.UIAxes
        ResponseAxes      matlab.ui.control.UIAxes
        ControlPanel      matlab.ui.container.Panel
        ControlGrid       matlab.ui.container.GridLayout
        StatusLabel       matlab.ui.control.Label
        ExportButton      matlab.ui.control.Button
        StopButton        matlab.ui.control.Button
        PlayButton        matlab.ui.control.Button
        ProcessButton     matlab.ui.control.Button
        TrebleSlider      matlab.ui.control.Slider
        TrebleValueLabel  matlab.ui.control.Label
        TrebleLabel       matlab.ui.control.Label
        MidSlider         matlab.ui.control.Slider
        MidValueLabel     matlab.ui.control.Label
        MidLabel          matlab.ui.control.Label
        BassSlider        matlab.ui.control.Slider
        BassValueLabel    matlab.ui.control.Label
        BassLabel         matlab.ui.control.Label
    end


    properties (Access = private)
        AudioSamples
        Config
        FilterBank
        OutputSamples
        Player
        ProjectRoot
        SampleRate
    end

    methods (Access = private)

        function exportOutput(app)
            outputFolder = fullfile(app.ProjectRoot,'audio','output');
            if ~isfolder(outputFolder)
                mkdir(outputFolder);
            end
            outputFile = fullfile(outputFolder,'Bass_Switch_30s_equalized.wav');
            audiowrite(outputFile,app.OutputSamples,app.SampleRate,'BitsPerSample',16);
            app.StatusLabel.Text = ['Exported: ' outputFile];
        end

        function gainDB = getGainDB(app)
            gainDB = [app.BassSlider.Value app.MidSlider.Value app.TrebleSlider.Value];
        end

        function playOutput(app)
            app.stopOutput();
            app.Player = audioplayer(app.OutputSamples,app.SampleRate);
            play(app.Player);
            app.StatusLabel.Text = 'Playing equalized output.';
        end

        function processAudio(app)
            app.Config.equalizer.defaultGainDB = app.getGainDB();
            [app.OutputSamples,details] = applyEqualizer(app.AudioSamples,app.FilterBank,app.Config.equalizer.defaultGainDB,app.Config.output);
            app.updateWaveform(app.AudioSamples);
            app.StatusLabel.Text = sprintf('Processed. Peak %.3f; normalization scale %.3f.',details.outputPeak,details.outputScale);
        end

        function stopOutput(app)
            if ~isempty(app.Player) && isvalid(app.Player)
                stop(app.Player);
            end
        end

        function updateGainLabels(app)
            app.BassValueLabel.Text = sprintf('%+.1f dB',app.BassSlider.Value);
            app.MidValueLabel.Text = sprintf('%+.1f dB',app.MidSlider.Value);
            app.TrebleValueLabel.Text = sprintf('%+.1f dB',app.TrebleSlider.Value);
        end

        function updateResponse(app)
            gainLinear = 10.^(app.getGainDB()/20);
            [bass,f] = freqz(app.FilterBank.bass.numerator,app.FilterBank.bass.denominator,2048,app.SampleRate);
            mid = freqz(app.FilterBank.mid.numerator,app.FilterBank.mid.denominator,2048,app.SampleRate);
            treble = freqz(app.FilterBank.treble.numerator,app.FilterBank.treble.denominator,2048,app.SampleRate);
            combined = gainLinear(1)*bass + gainLinear(2)*mid + gainLinear(3)*treble;
            cla(app.ResponseAxes);
            semilogx(app.ResponseAxes,f,20*log10(max(abs(combined),realmin('double'))),'b','LineWidth',1.2);
            grid(app.ResponseAxes,'on');
            xlim(app.ResponseAxes,[20 app.SampleRate/2]);
            ylim(app.ResponseAxes,[-40 20]);
            title(app.ResponseAxes,'Combined Equalizer Frequency Response');
            xlabel(app.ResponseAxes,'Frequency (Hz)');
            ylabel(app.ResponseAxes,'Magnitude (dB)');
        end

        function updateWaveform(app,inputSamples)
            time = (0:size(inputSamples,1)-1)'/app.SampleRate;
            cla(app.WaveformAxes);
            plot(app.WaveformAxes,time,mean(inputSamples,2),'Color',[0.3 0.3 0.3]);
            hold(app.WaveformAxes,'on');
            if ~isempty(app.OutputSamples)
                plot(app.WaveformAxes,time,mean(app.OutputSamples,2),'b');
                legend(app.WaveformAxes,{'Input','Equalized'},'Location','best');
            else
                legend(app.WaveformAxes,{'Input'},'Location','best');
            end
            hold(app.WaveformAxes,'off');
            grid(app.WaveformAxes,'on');
            title(app.WaveformAxes,'Waveform Comparison');
            xlabel(app.WaveformAxes,'Time (s)');
            ylabel(app.WaveformAxes,'Amplitude');
        end

    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.ProjectRoot = fileparts(fileparts(mfilename('fullpath')));
            addpath(fullfile(app.ProjectRoot,'functions'));
            app.Config = getProjectConfig();
            audioFile = fullfile(app.ProjectRoot,'audio','Bass_Switch_30s.wav');
            [app.AudioSamples,app.SampleRate,~] = loadAudioFile(audioFile);
            app.FilterBank = designEQFilters(app.SampleRate,app.Config);
            app.updateGainLabels();
            app.updateResponse();
            app.updateWaveform(app.AudioSamples);
            app.StatusLabel.Text = 'Audio loaded.';
        end

        % Value changed function: BassSlider, MidSlider, TrebleSlider
        function gainChanged(app, event)
            app.updateGainLabels();
            app.updateResponse();
        end

        % Button pushed function: ProcessButton
        function processButtonPushed(app, event)
            app.processAudio();
        end

        % Button pushed function: PlayButton
        function playButtonPushed(app, event)
            app.processAudio();
            app.playOutput();
        end

        % Button pushed function: StopButton
        function stopButtonPushed(app, event)
            app.stopOutput();
        end

        % Button pushed function: ExportButton
        function exportButtonPushed(app, event)
            app.processAudio();
            app.exportOutput();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'EqualizerApp';
            app.MainGrid = uigridlayout(app.UIFigure);
            app.ControlPanel = uipanel(app.MainGrid);
            app.ControlPanel.Title = 'Equalizer Controls';
            app.ControlGrid = uigridlayout(app.ControlPanel);
            app.BassLabel = uilabel(app.ControlGrid);
            app.BassLabel.Text = 'Bass (below 250 Hz)';
            app.BassValueLabel = uilabel(app.ControlGrid);
            app.BassValueLabel.Text = '+0 dB';
            app.BassSlider = uislider(app.ControlGrid);
            app.BassSlider.Limits = [-12 12];
            app.BassSlider.MajorTicks = [-12 -6 0 6 12];
            app.BassSlider.ValueChangedFcn = createCallbackFcn(app, @gainChanged, true);
            app.MidLabel = uilabel(app.ControlGrid);
            app.MidLabel.Text = 'Mid (250 to 4000 Hz)';
            app.MidValueLabel = uilabel(app.ControlGrid);
            app.MidValueLabel.Text = '+0 dB';
            app.MidSlider = uislider(app.ControlGrid);
            app.MidSlider.Limits = [-12 12];
            app.MidSlider.MajorTicks = [-12 -6 0 6 12];
            app.MidSlider.ValueChangedFcn = createCallbackFcn(app, @gainChanged, true);
            app.TrebleLabel = uilabel(app.ControlGrid);
            app.TrebleLabel.Text = 'Treble (above 4000 Hz)';
            app.TrebleValueLabel = uilabel(app.ControlGrid);
            app.TrebleValueLabel.Text = '+0 dB';
            app.TrebleSlider = uislider(app.ControlGrid);
            app.TrebleSlider.Limits = [-12 12];
            app.TrebleSlider.MajorTicks = [-12 -6 0 6 12];
            app.TrebleSlider.ValueChangedFcn = createCallbackFcn(app, @gainChanged, true);
            app.ProcessButton = uibutton(app.ControlGrid, 'push');
            app.ProcessButton.ButtonPushedFcn = createCallbackFcn(app, @processButtonPushed, true);
            app.ProcessButton.Text = 'Process Audio';
            app.PlayButton = uibutton(app.ControlGrid, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @playButtonPushed, true);
            app.PlayButton.Text = 'Play Output';
            app.StopButton = uibutton(app.ControlGrid, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @stopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.ExportButton = uibutton(app.ControlGrid, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @exportButtonPushed, true);
            app.ExportButton.Text = 'Export 16-bit WAV';
            app.StatusLabel = uilabel(app.ControlGrid);
            app.StatusLabel.Text = 'Loading audio...';
            app.DisplayGrid = uigridlayout(app.MainGrid);
            app.ResponseAxes = uiaxes(app.DisplayGrid);
            app.WaveformAxes = uiaxes(app.DisplayGrid);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EqualizerApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end